classdef eval_SN_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EvaluationofSNtestdataLabel    matlab.ui.control.Label
        SlopeButtonGroup               matlab.ui.container.ButtonGroup
        freeButton                     matlab.ui.control.ToggleButton
        fixedButton                    matlab.ui.control.ToggleButton
        kEditFieldLabel                matlab.ui.control.Label
        kEditField                     matlab.ui.control.NumericEditField
        TestdataPanel                  matlab.ui.container.Panel
        ImporttestdatafromExcelButton  matlab.ui.control.Button
        nEditFieldLabel                matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        CleardataButton                matlab.ui.control.Button
        StandarddeviationFailureprobabilityPanel  matlab.ui.container.Panel
        MultipleofstandarddeviationEditFieldLabel  matlab.ui.control.Label
        MultipleofstandarddeviationEditField  matlab.ui.control.NumericEditField
        ProbabiltyoffailureEditFieldLabel  matlab.ui.control.Label
        ProbabiltyoffailureEditField   matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UsemanualsettingsCheckBox      matlab.ui.control.CheckBox
        Button                         matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
    end

    methods (Access = private)
    
        function createplot(app)
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            if isfield(app.Property,'data')
                [z,s] = size(app.Property.data);
                legstr = {'Test data'};
                legend(app.UIAxes,'show');
                for ii = 1:1:z
                    plot(app.UIAxes,app.Property.data(ii,2),app.Property.data(ii,1),'Color','k','Marker','x');
                end
                h = plot(app.UIAxes,NaN,NaN,'Color','k','Marker','x');
                % Plot DIN50100
                    loglog(app.UIAxes,...
                        [app.Property.res.DIN50100.N(1);app.Property.res.DIN50100.N(2)],...
                        [app.Property.res.DIN50100.d_sigma(1,1);app.Property.res.DIN50100.d_sigma(2,1)],...
                        'Color','b',...
                        'LineStyle','--');
                    loglog(app.UIAxes,...
                        [app.Property.res.DIN50100.N(1);app.Property.res.DIN50100.N(2)],...
                        [app.Property.res.DIN50100.d_sigma(1,2);app.Property.res.DIN50100.d_sigma(2,2)],...
                        'Color','b',...
                        'LineStyle','-');
                    loglog(app.UIAxes,...
                        [app.Property.res.DIN50100.N(1);app.Property.res.DIN50100.N(2)],...
                        [app.Property.res.DIN50100.d_sigma(1,3);app.Property.res.DIN50100.d_sigma(2,3)],...
                        'Color','b',...
                        'LineStyle','--');
                    legstradd = {'DIN50100'};
                    legstr = [legstr;legstradd];
                    h = [h;plot(app.UIAxes,NaN,NaN,'Color','b','LineStyle','-')];
                % Plot IIW
                    loglog(app.UIAxes,...
                        [app.Property.res.IIW.N(1);app.Property.res.IIW.N(2)],...
                        [app.Property.res.IIW.d_sigma(1,1);app.Property.res.IIW.d_sigma(2,1)],...
                        'Color','r',...
                        'LineStyle','--');
                    loglog(app.UIAxes,...
                        [app.Property.res.IIW.N(1);app.Property.res.IIW.N(2)],...
                        [app.Property.res.IIW.d_sigma(1,2);app.Property.res.IIW.d_sigma(2,2)],...
                        'Color','r',...
                        'LineStyle','-');
                    loglog(app.UIAxes,...
                        [app.Property.res.IIW.N(1);app.Property.res.IIW.N(2)],...
                        [app.Property.res.IIW.d_sigma(1,3);app.Property.res.IIW.d_sigma(2,3)],...
                        'Color','r',...
                        'LineStyle','--');
                    legstradd = {'IIW'};
                    legstr = [legstr;legstradd];
                    h = [h;plot(app.UIAxes,NaN,NaN,'Color','r','LineStyle','-')];
                legend(app.UIAxes,h);
                app.UIAxes.Legend.String = legstr;
                app.UIAxes.Legend.Location = 'southwest';
            end
        end
        
        function calc_data(app)
            if app.UsemanualsettingsCheckBox.Value == 0
                app.Property.n_stdv = [];
                app.Property.k = [];
            else
                app.Property.n_stdv = app.MultipleofstandarddeviationEditField.Value;
                if app.fixedButton.Value == 1
                    app.Property.k = app.kEditField.Value;
                else
                    app.Property.k = [];
                end
            end
            if isfield(app.Property,'data')
                % DIN50100
                N_A = app.Property.data(:,2);
                L_a = app.Property.data(:,1);
                [app.Property.res.DIN50100.N,...
                 app.Property.res.DIN50100.d_sigma,...
                 app.Property.res.DIN50100.k,...
                 app.Property.res.DIN50100.s_log_N] =...
                    ber_DIN50100(N_A,L_a,app.Property.n_stdv,app.Property.k);
                % IIW
                N_A = app.Property.data(:,2);
                L_a = app.Property.data(:,1);
                [app.Property.res.IIW.N,...
                 app.Property.res.IIW.d_sigma,...
                 app.Property.res.IIW.k,...
                 app.Property.res.IIW.s_log_N] =...
                    ber_IIW(N_A,L_a);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnName = {'#',[char(916),char(963),' [MPa]'],'N_a [ ]'};
            n = app.nEditField.Value;
            app.UITable.Data = cell(n,3);
            for ii = 1:1:n
                app.UITable.Data{ii,1} = ii;
            end
            app.UIAxes.XScale = 'log';
            app.UIAxes.YScale = 'log';
            app.UIAxes.XLim =[1*10^4 5*10^6];
            app.UIAxes.YLim =[1*10^1 1*10^3];
            % Vielfache der Standardabweichung für den Plot
            app.Property.n_stdv = 2;
            app.ProbabiltyoffailureEditField.Value = 0.5*(1+erf(app.MultipleofstandarddeviationEditField.Value/sqrt(2)));
        end

        % Button pushed function: CleardataButton
        function CleardataButtonPushed(app, event)
            if isfield(app.Property,'data')
                app.Property.data = [];
            end
            app.UITable.Data = cell(app.Property.n,3);
            for ii = 1:1:app.Property.n
                app.UITable.Data{ii,1} = ii;
            end
            cla(app.UIAxes);
        end

        % Button pushed function: ImporttestdatafromExcelButton
        function ImporttestdatafromExcelButtonPushed(app, event)
            data = xlsread(uigetfile);
            if mean(data(:,1)) > mean(data(:,2))
                data2(:,1) = data(:,2);
                data2(:,2) = data(:,1);
                data = data2;
            end
            [z,s] = size(data);
            s1 = [1:z]';
            app.UITable.Data = [s1,data];
            app.Property.data = data;
            app.nEditField.Value = z;
            app.Property.n = z;
            app.Property.res = [];
            calc_data(app);
            createplot(app);
        end

        % Value changed function: MultipleofstandarddeviationEditField
        function MultipleofstandarddeviationEditFieldValueChanged(app, event)
            value = app.MultipleofstandarddeviationEditField.Value;
            app.ProbabiltyoffailureEditField.Value = 0.5*(1+erf(value/sqrt(2)));
            calc_data(app);
            createplot(app);
        end

        % Value changed function: ProbabiltyoffailureEditField
        function ProbabiltyoffailureEditFieldValueChanged(app, event)
            value = app.ProbabiltyoffailureEditField.Value;
            app.MultipleofstandarddeviationEditField.Value = abs(sqrt(2)*erfinv(1-2*value));
            calc_data(app);
            createplot(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            data = app.UITable.Data(:,2:3);
            [z,s] = size(data);
            data2 = data;
            if iscell(data)
            for ii = 1:1:z
                for jj = 1:1:s
                    if ischar(data{ii,jj}) && isempty(str2num(data{ii,jj}))
                        data{ii,jj} = 'NaN';
                        app.UITable.Data{ii,jj+1} = data{ii,jj};
                    end
                    if ischar(data2{ii,jj})
                        data2{ii,jj} = str2num(data2{ii,jj});
                    end
                    if isempty(data2{ii,jj})
                        data2{ii,jj} = NaN;
                    end
                end
            end
            data2 = cell2mat(data2);
            end
            app.Property.data = data2;
            app.Property.res = [];
            calc_data(app);
            createplot(app);
        end

        % Value changed function: UsemanualsettingsCheckBox
        function UsemanualsettingsCheckBoxValueChanged(app, event)
            value = app.UsemanualsettingsCheckBox.Value;
            if value == 1
                calc_data(app);
                createplot(app);
            else
                app.Property.k = [];
                app.Property.n_stdv = [];
                calc_data(app);
                createplot(app);
            end
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            app.Property.n = app.nEditField.Value;
            app.UITable.Data = cell(app.Property.n,3);
            for ii = 1:1:app.Property.n
                app.UITable.Data{ii,1} = ii;
            end
            [z,s] = size(app.Property.data);
            [z2,s2] = size(app.UITable.Data);
            if z < z2
                z = z;
            else
                z = z2;
            end
            for ii = 1:1:z
                app.UITable.Data{ii,2} = app.Property.data(ii,1);
                app.UITable.Data{ii,3} = app.Property.data(ii,2);
            end
            app.Property.data([(z+1):end],:) = []; 
            if isempty(app.Property.data) == 0
                calc_data(app);
                createplot(app);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            helpdlg({'Slope:';...
                     '';...
                     'Standard settings: Auto-determination';...
                     '';...
                     'Manual settings: Using a manual slope setting is only possible for an evaluation according to DIN 50100 using test data on more than two load levels. Evaluation according to DIN 50100 with test data on only two load levels and evaluation according to IIW auto-determines the slope.';...
                     '';...
                     '';...
                     'Probability of failure, Multiples of standard deviation:';...
                     '';...
                     'Standard settings: Probability of failure 97,7% or two times the standard deviation.';...
                     '';...
                     'Using a manual setting of the failure probability or the multiples of standard deviation is only possible for evaluation according to DIN 50100. Evaluation according to IIW auto-determines the corresponding values.'},...
                     'Remarks on using manual settings');
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            value = app.kEditField.Value;
            calc_data(app);
            createplot(app);            
        end

        % Selection changed function: SlopeButtonGroup
        function SlopeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SlopeButtonGroup.SelectedObject;
            calc_data(app);
            createplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EvaluationofSNtestdataLabel = uilabel(app.UIFigure);
            app.EvaluationofSNtestdataLabel.Text = 'Evaluation of S-N-test data';
            app.SlopeButtonGroup = uibuttongroup(app.UIFigure);
            app.SlopeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SlopeButtonGroupSelectionChanged, true);
            app.SlopeButtonGroup.Title = 'Slope';
            app.freeButton = uitogglebutton(app.SlopeButtonGroup);
            app.freeButton.Text = 'free';
            app.freeButton.Value = true;
            app.fixedButton = uitogglebutton(app.SlopeButtonGroup);
            app.fixedButton.Text = 'fixed';
            app.kEditFieldLabel = uilabel(app.SlopeButtonGroup);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.SlopeButtonGroup, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Limits = [0 Inf];
            app.kEditField.ValueDisplayFormat = '%.2f';
            app.TestdataPanel = uipanel(app.UIFigure);
            app.TestdataPanel.Title = 'Test data';
            app.ImporttestdatafromExcelButton = uibutton(app.TestdataPanel, 'push');
            app.ImporttestdatafromExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ImporttestdatafromExcelButtonPushed, true);
            app.ImporttestdatafromExcelButton.Text = 'Import test data from Excel';
            app.nEditFieldLabel = uilabel(app.TestdataPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.TestdataPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.nEditField.Limits = [1 Inf];
            app.nEditField.RoundFractionalValues = 'on';
            app.nEditField.ValueDisplayFormat = '%.0f';
            app.nEditField.Value = 10;
            app.UITable = uitable(app.TestdataPanel);
            app.UITable.ColumnName = {'#'; '\Delta\sigma'; 'N'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CleardataButton = uibutton(app.TestdataPanel, 'push');
            app.CleardataButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataButtonPushed, true);
            app.CleardataButton.Text = 'Clear data';
            app.StandarddeviationFailureprobabilityPanel = uipanel(app.UIFigure);
            app.StandarddeviationFailureprobabilityPanel.Title = 'Standard deviation / Failure probability';
            app.MultipleofstandarddeviationEditFieldLabel = uilabel(app.StandarddeviationFailureprobabilityPanel);
            app.MultipleofstandarddeviationEditFieldLabel.Text = 'Multiple of standard deviation';
            app.MultipleofstandarddeviationEditField = uieditfield(app.StandarddeviationFailureprobabilityPanel, 'numeric');
            app.MultipleofstandarddeviationEditField.LowerLimitInclusive = 'off';
            app.MultipleofstandarddeviationEditField.ValueChangedFcn = createCallbackFcn(app, @MultipleofstandarddeviationEditFieldValueChanged, true);
            app.MultipleofstandarddeviationEditField.Limits = [0 Inf];
            app.MultipleofstandarddeviationEditField.ValueDisplayFormat = '%.2f';
            app.MultipleofstandarddeviationEditField.Value = 2;
            app.ProbabiltyoffailureEditFieldLabel = uilabel(app.StandarddeviationFailureprobabilityPanel);
            app.ProbabiltyoffailureEditFieldLabel.Text = 'Probabilty of failure';
            app.ProbabiltyoffailureEditField = uieditfield(app.StandarddeviationFailureprobabilityPanel, 'numeric');
            app.ProbabiltyoffailureEditField.LowerLimitInclusive = 'off';
            app.ProbabiltyoffailureEditField.UpperLimitInclusive = 'off';
            app.ProbabiltyoffailureEditField.ValueChangedFcn = createCallbackFcn(app, @ProbabiltyoffailureEditFieldValueChanged, true);
            app.ProbabiltyoffailureEditField.Limits = [0 1];
            app.ProbabiltyoffailureEditField.ValueDisplayFormat = '%.3f';
            app.ProbabiltyoffailureEditField.Value = 0.9;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'S-N-curve')
            xlabel(app.UIAxes, 'N [ ]')
            ylabel(app.UIAxes, '\Delta\sigma [MPa]')
            app.UsemanualsettingsCheckBox = uicheckbox(app.UIFigure);
            app.UsemanualsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsemanualsettingsCheckBoxValueChanged, true);
            app.UsemanualsettingsCheckBox.Text = 'Use manual settings';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
        end
    end

    methods (Access = public)

        % Construct app
        function app = eval_SN_data

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end